classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        computeButton  matlab.ui.control.Button
        loadButton     matlab.ui.control.Button
        UIAxes_2       matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            
            img1 = imread('./logo_csse.png');
            imshow(img1, 'Parent', app.UIAxes);
        
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            img1 = imread('./logo_csse.png');
            
            % compute image 
            imgray = rgb2gray(img1); 
            BW = imbinarize(imgray); 
            % plot images imshow(BW*255);
            imshow(BW, 'Parent', app.UIAxes_2);
        end

        % Callback function: not associated with a component
        function compute2ButtonPushed(app, event)
            img1 = imread('./logo_csse.png');
            
            % compute image 
            imgray = rgb2gray(img1); 
            BW = imbinarize(imgray); 
            % plot images imshow(BW*255);
            imshow(BW*25500000, 'Parent', app.UIAxes2_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '画像表示')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '二値化')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end